classdef AppRNI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Image2                          matlab.ui.control.Image
        EscolhaosarquivosdasmedidasLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        PesquisaTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SelecioneosGRUOsdesejadosLabel  matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        DistEditField                   matlab.ui.control.NumericEditField
        DistnciamEditFieldLabel         matlab.ui.control.Label
        MapaButton                      matlab.ui.control.Button
        GerarKMLButton                  matlab.ui.control.Button
        MaioresnveisButton              matlab.ui.control.Button
        SelecioneasLocalidadesdesejadasLabel  matlab.ui.control.Label
        ListBox_GR_UO                   matlab.ui.control.ListBox
        CadastroTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        EditField                       matlab.ui.control.NumericEditField
        EditFieldRow                    matlab.ui.control.NumericEditField
        GravardadosButton               matlab.ui.control.Button
        InserirnatabelaButton           matlab.ui.control.Button
        LongitudedaestaoEditField       matlab.ui.control.EditField
        LongitudedaestaoEditFieldLabel  matlab.ui.control.Label
        LatitudedaestaoEditField        matlab.ui.control.EditField
        LatitudedaestaoLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DatadamedioEditField            matlab.ui.control.EditField
        DatadamedioEditFieldLabel       matlab.ui.control.Label
        ServioEditField                 matlab.ui.control.EditField
        ServioEditFieldLabel            matlab.ui.control.Label
        MunicpioUFEditField             matlab.ui.control.EditField
        MunicpioUFLabel                 matlab.ui.control.Label
        EstaoNEditField                 matlab.ui.control.EditField
        EstaoNEditFieldLabel            matlab.ui.control.Label
        DistnciaKmEditField             matlab.ui.control.EditField
        DistnciaKmEditFieldLabel        matlab.ui.control.Label
        ObservaesEditField              matlab.ui.control.EditField
        ObservaesEditFieldLabel         matlab.ui.control.Label
        JustificativadeNVEditField      matlab.ui.control.EditField
        MotivoNoverificadasLabel        matlab.ui.control.Label
        Medidas14VMEditField            matlab.ui.control.EditField
        Medidas14VMEditFieldLabel       matlab.ui.control.Label
        LongitudemaiorVmEditField       matlab.ui.control.EditField
        LongitudemaiorVmEditFieldLabel  matlab.ui.control.Label
        LatitudeMaiorVmEditField        matlab.ui.control.EditField
        LatitudeMaiorVmEditFieldLabel   matlab.ui.control.Label
        CampomaiorVmEditField           matlab.ui.control.EditField
        CampomaiorVmEditFieldLabel      matlab.ui.control.Label
        SelecioneaestaonatabelaLabel    matlab.ui.control.Label
        ListaPARNILabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data_PA_RNI; % Table PA_RNI
        Data_Meas_Sondas; %Table Meas Sondas
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
            
            currentDate = datetime('now');
            
            app.ListaPARNILabel.Text = sprintf('Lista de estações referentes ao Plano Anual de Fiscalização de RNI de %d',year(currentDate));
            
            app.Data_PA_RNI = fcn.ReadFile_PA_RNI(app);

            app.UITable.Data = app.Data_PA_RNI;
            % Definir os nomes das colunas da `UITable`
            app.UITable.ColumnName = class.Constants.GUIColumns;
      
        end

        % Value changed function: ListBox_GR_UO
        function ListBox_GR_UOValueChanged(app, event)
          
            value = string(app.ListBox_GR_UO.Value);
            ER_UO = app.Data_PA_RNI(:,2);
            Index_Local = find(strcmp(string(table2array(ER_UO)), value));
            app.UITable.Data =  app.Data_PA_RNI(Index_Local,:);
            Localidades = unique(app.Data_PA_RNI(Index_Local,4)); 

            delete(app.Tree.Children);
            
            for ii = 1:numel(Localidades)
                uitreenode(app.Tree,"Text",string(table2array(Localidades(ii,1))));
            end
            expand(app.Tree)
        end

        % Button pushed function: MaioresnveisButton
        function MaioresnveisButtonPushed(app, event)
      
            Dist_Calc_Maior_Nivel = app.DistEditField.Value; 
            
            % E_Nivel = table2array(app.yx(:,3));
            Lat_Med_Dec = cell2mat(app.Data_Meas_Sondas(:,2));
            Long_Med_Dec = cell2mat(app.Data_Meas_Sondas(:,3));

            Data_Med = app.Data_Meas_Sondas(:,1);

            dist_EMax = (Dist_Calc_Maior_Nivel/111.12)/1000;

            for jj=1:height(app.UITable.Data)
                Lat_Estacao  = -1*(str2double(replace(string(table2array(app.UITable.Data(jj,8))),",",".")));

                Long_Estacao = -1*(str2double(replace(string(table2array(app.UITable.Data(jj,9))),",",".")));
            
                Lat_Max = Lat_Estacao + dist_EMax;
                Lat_Min = Lat_Estacao - dist_EMax;
                Long_Max = Long_Estacao + dist_EMax;
                Long_Min = Long_Estacao - dist_EMax;
                
                filteredT = app.Data_Meas_Sondas((Lat_Med_Dec >= Lat_Min & Lat_Med_Dec <= Lat_Max) & (Long_Med_Dec >= Long_Min & Long_Med_Dec <= Long_Max), :);
                [~, maxIndex] =  max(str2double(filteredT(:,4)));
                if ~isempty(maxIndex)
        
                    Lat_Ft_Dec = cell2mat(filteredT(:,2));                    
                    Long_Ft_Dec = cell2mat(filteredT(:,3));
                    E_Nivel = str2double(filteredT(:,4));

                    % distancia = [];
                    E_Nivel_Maior = 0;

                    % maxValue = max(T.Lat_Med_Dec);
                    Lat_Max_Calc = 0;
                    Long_Max_Calc = 0;
                    Data_Med_Max = 0;
                    distancia_Max_Value = "";
                    ContMaior14Vm = 0;

                    for kk = 1:height(filteredT)
                        if Lat_Med_Dec(kk)~=0
                            distancia = (sqrt(double(((((Lat_Estacao - Lat_Ft_Dec(kk))*111.12)^2) + (((Long_Estacao - Long_Ft_Dec(kk))*111.12)^2)))))*1000;
                            E_Nivel_Atual = E_Nivel(kk);
                            if distancia <= Dist_Calc_Maior_Nivel
                                if E_Nivel_Atual >= E_Nivel_Maior
                                    format long;
                                    E_Nivel_Maior = E_Nivel_Atual;
                                    Lat_Max_Calc = -1*(Lat_Ft_Dec(kk));
                                    Long_Max_Calc = -1*(Long_Ft_Dec(kk));
                                    Data_Med_Max = Data_Med(kk);
                                    distancia_Max_Value = distancia;
                                    if E_Nivel_Atual >= 14
                                        ContMaior14Vm = ContMaior14Vm +1; 
                                    end
                                end
                            end
                        end                      
                    end
                    % Modificar o valor existente   
                    if Lat_Max_Calc ~= 0
                        app.UITable.Data{jj, 11} = cellstr(num2str(E_Nivel_Maior));
                        app.UITable.Data{jj, 12} = cellstr(num2str(Lat_Max_Calc, '%.8f'));
                        app.UITable.Data{jj, 13} = cellstr(num2str(Long_Max_Calc, '%.8f'));
                        app.UITable.Data(jj, 14) = cellstr(num2str(ContMaior14Vm));
                        app.UITable.Data{jj, 10} = cellstr(num2str(string(Data_Med_Max)));                        
                        app.UITable.Data{jj, 17} = cellstr(num2str(distancia_Max_Value));
                    end                    
                end
               
            end
            app.UITable.ColumnWidth = {1, 1, 1, 1, 1, 1, 100, 1, 1, 80, 70, 100, 100, 80, 140, 140, 80};
            %msgbox("Fim da analise")
            uialert(app.UIFigure, 'Cálulos dos Campos elétricos máximos (EMax) realizados com sucesso!', 'Tudo certo!','Icon','success')
        end

        % Button pushed function: MapaButton
        function MapaButtonPushed(app, event)
            
            Latidude_KML = -1*(cell2mat(app.Data_Meas_Sondas(:,2)));
            Longitude_KML = -1*(cell2mat(app.Data_Meas_Sondas(:,3)));           

            geoscatter(Latidude_KML,Longitude_KML,"filled")

        end

        % Button pushed function: GerarKMLButton
        function GerarKMLButtonPushed(app, event)

            Latidude_KML = -1*(cell2mat(app.Data_Meas_Sondas(:,2)));
            Longitude_KML = -1*(cell2mat(app.Data_Meas_Sondas(:,3)));

            E_Nivel = str2double(app.Data_Meas_Sondas(:,4));
            

            % Nome do arquivo KML
            kmlFileName = 'Percurso.kml';
            
            % Abrir o arquivo KML para escrita
            fileID = fopen(kmlFileName, 'w');
            
            % Escrever cabeçalho do KML
            fprintf(fileID, '<?xml version="1.0" encoding="UTF-8"?>\n');
            fprintf(fileID, '<kml xmlns="http://www.opengis.net/kml/2.2">\n');
            fprintf(fileID, '<Document>\n');
            fprintf(fileID, '<name>Monitem RNI</name>\n');
            
            % Definir estilo de ícone para bolinhas vermelhas
            fprintf(fileID, '<Style id="redBallStyle">\n');
            fprintf(fileID, '  <IconStyle>\n');
            fprintf(fileID, '    <Icon>\n');
            fprintf(fileID, '      <href>http://maps.google.com/mapfiles/kml/paddle/red-circle.png</href>\n');
            fprintf(fileID, '    </Icon>\n');
            fprintf(fileID, '  </IconStyle>\n');
            fprintf(fileID, '</Style>\n');

            % Escrever as coordenadas
            for i = 1:length(Latidude_KML)
                fprintf(fileID, '<Placemark>\n');
                % fprintf(fileID, '  <name>%s</name>\n', P{i});
                fprintf(fileID, '  <description>%.6f, %.6f, %.6f</description>\n',Latidude_KML(i),Longitude_KML(i), E_Nivel(i));    
                fprintf(fileID, '  <styleUrl>#redBallStyle</styleUrl>\n');
                fprintf(fileID, '  <Point>\n');
                fprintf(fileID, '<coordinates>%.6f,%.6f</coordinates>\n', Longitude_KML(i), Latidude_KML(i));
                fprintf(fileID, '</Point>\n');
                fprintf(fileID, '</Placemark>\n');
            end
            
            % Fechar os elementos KML
            fprintf(fileID, '</Document>\n');
            fprintf(fileID, '</kml>\n');
            
            % Fechar o arquivo
            fclose(fileID);
            
            disp('Arquivo KML criado com sucesso.');

            % Caminho para o executável do Google Earth
            googleEarthPath = '"C:\Program Files\Google\Google Earth Pro\client\googleearth.exe"';
            
            % Caminho para o arquivo KML
            kmlFilePath = ('C:\P&D\AppRNI\Percurso.kml');
            
            % Comando para abrir o arquivo KML no Google Earth
            command = [googleEarthPath, ' "', kmlFilePath, '"'];
            
            % Executar o comando
            system(command);

            % Ou fechar todas as figuras
            close all;
            
            % Desativar o uso de OpenGL para futuras figuras
            opengl neverselect;
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            selectedNodes = app.Tree.CheckedNodes;
    
            GR_UO_Select = string(app.ListBox_GR_UO.Value);
            idxLogical_GR_UO = find(strcmp(string(table2array(app.Data_PA_RNI(:,2))), GR_UO_Select));
            py = app.Data_PA_RNI(idxLogical_GR_UO,:);
            idxLogical_Cach = [];

            for pp = 1:numel(selectedNodes)
                idxLogical  = find(strcmp(string(table2array(py(:,4))), string(selectedNodes(pp).Text)));
                idxLogical  = unique([idxLogical_Cach; idxLogical], 'stable');
                idxLogical_Cach = idxLogical;
            end
            if ~isempty(idxLogical_Cach)
                app.UITable.Data = py(idxLogical,:);
            else
                app.UITable.Data = py;
            end
        end

        % Button pushed function: InserirnatabelaButton
        function InserirnatabelaButtonPushed(app, event)
           % Modificar o valor existente
           linha = app.EditFieldRow.Value;
           app.UITable.Data{linha , 15} = cellstr(app.JustificativadeNVEditField.Value);
           app.UITable.Data(linha , 16) = cellstr(app.ObservaesEditField.Value);
           app.UITable.Data(linha , 17) = cellstr(app.DistnciaKmEditField.Value);
        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
   try
            app.TabGroup.SelectedTab = app.CadastroTab;
            displayRow = event.InteractionInformation.DisplayRow;
            app.EditFieldRow.Value = displayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            app.EstaoNEditField.Value = string(table2array(app.UITable.Data(displayRow,7)));
            app.MunicpioUFEditField.Value = strcat(string(table2array(app.UITable.Data(displayRow,4))),"/",string(table2array(app.UITable.Data(displayRow,3))));
            app.ServioEditField.Value = string(table2array(app.UITable.Data(displayRow,5)));            
            app.LatitudedaestaoEditField.Value = string(table2array(app.UITable.Data(displayRow,8)));
            app.LongitudedaestaoEditField.Value = string(table2array(app.UITable.Data(displayRow,9)));
            app.DatadamedioEditField.Value = string(table2array(app.UITable.Data(displayRow,10)));
            app.CampomaiorVmEditField.Value = string(table2array(app.UITable.Data(displayRow,11)));     
            app.LatitudeMaiorVmEditField.Value = string(table2array(app.UITable.Data(displayRow,12)));
            app.LongitudemaiorVmEditField.Value = string(table2array(app.UITable.Data(displayRow,13)));  
            app.Medidas14VMEditField.Value = string(table2array(app.UITable.Data(displayRow,14)));
            app.ObservaesEditField.Value = string(table2array(app.UITable.Data(displayRow,16)));
            app.DistnciaKmEditField.Value = string(table2array(app.UITable.Data(displayRow,17)));
            catch
            end 
            
        end

        % Image clicked function: Image2
        function Image2Clicked2(app, event)
            % Definir o diretório inicial
            initialPath = 'C:\P&D\AppRNI\DataBase\Meas_Sondas';
            
            % Abrir a caixa de diálogo para selecionar múltiplos arquivos
            [filenames, pathname] = uigetfile({'*.txt';'*.csv';'*.mat';'*.*'}, ...
                                              'Selecione arquivos', initialPath, ...
                                              'MultiSelect', 'on');
            
            % Verificar se algum arquivo foi selecionado
            if isequal(filenames, 0)
                disp('Nenhum arquivo selecionado.');
            else
                % Se apenas um arquivo foi selecionado, filenames será uma string
                % Se múltiplos arquivos foram selecionados, filenames será um cell array
                File_Sondas = [];
                if ischar(filenames)
                    % Apenas um arquivo selecionado
                    fcn.Progressbar(app, 1)
                    File_Sondas = fullfile(pathname, filenames);
                    Type_Meas_Probes = fcn.TypeMeasProbe(app, File_Sondas);
                    DataRows = fcn.ReadFile_Meas_Sondas(app, Type_Meas_Probes, File_Sondas);
                    % Armazenar as tabelas em um cell array
                    app.Data_Meas_Sondas = DataRows;    
                else
                    % Múltiplos arquivos selecionados
                    for ii = 1:length(filenames)
                        File_Sondas{ii} = fullfile(pathname, filenames{ii});
                    end
                    app.Data_Meas_Sondas = [];
                    % fcn.Loop_ReadFiles(app);
                    for pp = 1:numel(File_Sondas)
                         fcn.Progressbar(app, pp);
                         Type_Meas_Probes = fcn.TypeMeasProbe(app, File_Sondas{pp});
                         DataRows = fcn.ReadFile_Meas_Sondas(app, Type_Meas_Probes, File_Sondas{pp});
                         % Armazenar as tabelas em um cell array
                         app.Data_Meas_Sondas = [app.Data_Meas_Sondas; DataRows];
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ListaPARNILabel = uilabel(app.GridLayout);
            app.ListaPARNILabel.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PesquisaTab = uitab(app.TabGroup);
            app.PesquisaTab.Title = 'Pesquisa';
            app.GridLayout2 = uigridlayout(app.PesquisaTab);
            app.ListBox_GR_UO = uilistbox(app.GridLayout2);
            app.ListBox_GR_UO.Items = {'GR01', 'GR02', 'GR03', 'GR04', 'GR05', 'GR06', 'GR07', 'GR08', 'GR09', 'GR10', 'GR11', 'UO001', 'UO021', 'UO031', 'UO061', 'UO062', 'UO071', 'UO072', 'UO073', 'UO081', 'UO091', 'UO092', 'UO101', 'UO111', 'UO112', 'UO113'};
            app.ListBox_GR_UO.Multiselect = 'on';
            app.ListBox_GR_UO.ValueChangedFcn = createCallbackFcn(app, @ListBox_GR_UOValueChanged, true);
            app.ListBox_GR_UO.Value = {};
            app.SelecioneasLocalidadesdesejadasLabel = uilabel(app.GridLayout2);
            app.SelecioneasLocalidadesdesejadasLabel.Text = 'Selecione a(s) Localidade(s) desejada(s):';
            app.MaioresnveisButton = uibutton(app.GridLayout2, 'push');
            app.MaioresnveisButton.ButtonPushedFcn = createCallbackFcn(app, @MaioresnveisButtonPushed, true);
            app.MaioresnveisButton.Text = 'Maiores níveis';
            app.GerarKMLButton = uibutton(app.GridLayout2, 'push');
            app.GerarKMLButton.ButtonPushedFcn = createCallbackFcn(app, @GerarKMLButtonPushed, true);
            app.GerarKMLButton.Text = 'Gerar KML';
            app.MapaButton = uibutton(app.GridLayout2, 'push');
            app.MapaButton.ButtonPushedFcn = createCallbackFcn(app, @MapaButtonPushed, true);
            app.MapaButton.Text = 'Mapa';
            app.DistnciamEditFieldLabel = uilabel(app.GridLayout2);
            app.DistnciamEditFieldLabel.Text = 'Distância (m):';
            app.DistEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DistEditField.Value = 200;
            app.Tree = uitree(app.GridLayout2, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.SelecioneosGRUOsdesejadosLabel = uilabel(app.GridLayout2);
            app.SelecioneosGRUOsdesejadosLabel.Text = 'Selecione o(s) GR/UO(s) desejado(s):';
            app.CadastroTab = uitab(app.TabGroup);
            app.CadastroTab.Title = 'Cadastro';
            app.GridLayout3 = uigridlayout(app.CadastroTab);
            app.SelecioneaestaonatabelaLabel = uilabel(app.GridLayout3);
            app.SelecioneaestaonatabelaLabel.Text = 'Selecione a estação na tabela:';
            app.CampomaiorVmEditFieldLabel = uilabel(app.GridLayout3);
            app.CampomaiorVmEditFieldLabel.Text = 'Campo maior V/m:';
            app.CampomaiorVmEditField = uieditfield(app.GridLayout3, 'text');
            app.LatitudeMaiorVmEditFieldLabel = uilabel(app.GridLayout3);
            app.LatitudeMaiorVmEditFieldLabel.Text = 'Latitude Maior V/m:';
            app.LatitudeMaiorVmEditField = uieditfield(app.GridLayout3, 'text');
            app.LongitudemaiorVmEditFieldLabel = uilabel(app.GridLayout3);
            app.LongitudemaiorVmEditFieldLabel.Text = 'Longitude maior V/m:';
            app.LongitudemaiorVmEditField = uieditfield(app.GridLayout3, 'text');
            app.Medidas14VMEditFieldLabel = uilabel(app.GridLayout3);
            app.Medidas14VMEditFieldLabel.Text = 'Medidas > 14 V/M:';
            app.Medidas14VMEditField = uieditfield(app.GridLayout3, 'text');
            app.MotivoNoverificadasLabel = uilabel(app.GridLayout3);
            app.MotivoNoverificadasLabel.Text = 'Justificativa de NV:';
            app.JustificativadeNVEditField = uieditfield(app.GridLayout3, 'text');
            app.JustificativadeNVEditField.Value = 'Não deu tempo!';
            app.ObservaesEditFieldLabel = uilabel(app.GridLayout3);
            app.ObservaesEditFieldLabel.Text = 'Observações:';
            app.ObservaesEditField = uieditfield(app.GridLayout3, 'text');
            app.DistnciaKmEditFieldLabel = uilabel(app.GridLayout3);
            app.DistnciaKmEditFieldLabel.Text = 'Distância (Km):';
            app.DistnciaKmEditField = uieditfield(app.GridLayout3, 'text');
            app.EstaoNEditFieldLabel = uilabel(app.GridLayout3);
            app.EstaoNEditFieldLabel.Text = 'Estação N º:';
            app.EstaoNEditField = uieditfield(app.GridLayout3, 'text');
            app.MunicpioUFLabel = uilabel(app.GridLayout3);
            app.MunicpioUFLabel.Text = 'Município/UF:';
            app.MunicpioUFEditField = uieditfield(app.GridLayout3, 'text');
            app.ServioEditFieldLabel = uilabel(app.GridLayout3);
            app.ServioEditFieldLabel.Text = 'Serviço:';
            app.ServioEditField = uieditfield(app.GridLayout3, 'text');
            app.DatadamedioEditFieldLabel = uilabel(app.GridLayout3);
            app.DatadamedioEditFieldLabel.Text = 'Data da medição:';
            app.DatadamedioEditField = uieditfield(app.GridLayout3, 'text');
            app.Image = uiimage(app.GridLayout3);
            app.LatitudedaestaoLabel = uilabel(app.GridLayout3);
            app.LatitudedaestaoLabel.Text = 'Latitude da estação:';
            app.LatitudedaestaoEditField = uieditfield(app.GridLayout3, 'text');
            app.LongitudedaestaoEditFieldLabel = uilabel(app.GridLayout3);
            app.LongitudedaestaoEditFieldLabel.Text = 'Longitude da estação:';
            app.LongitudedaestaoEditField = uieditfield(app.GridLayout3, 'text');
            app.InserirnatabelaButton = uibutton(app.GridLayout3, 'push');
            app.InserirnatabelaButton.ButtonPushedFcn = createCallbackFcn(app, @InserirnatabelaButtonPushed, true);
            app.InserirnatabelaButton.Text = 'Inserir na tabela';
            app.GravardadosButton = uibutton(app.GridLayout3, 'push');
            app.GravardadosButton.Text = 'Gravar dados';
            app.EditFieldRow = uieditfield(app.GridLayout3, 'numeric');
            app.EditField = uieditfield(app.GridLayout3, 'numeric');
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.EscolhaosarquivosdasmedidasLabel = uilabel(app.GridLayout);
            app.EscolhaosarquivosdasmedidasLabel.Text = 'Escolha o(s) arquivo(s) das medida(s):';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked2, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'files.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppRNI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end